classdef electric_field < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        interfaz              matlab.ui.Figure
        Calcular              matlab.ui.control.Button
        Etotal                matlab.ui.control.Table
        tabla_medicion        matlab.ui.control.Table
        PuntodemedicionLabel  matlab.ui.control.Label
        numero_cargas         matlab.ui.control.NumericEditField
        NumerodecargasLabel   matlab.ui.control.Label
        tabla_cargas          matlab.ui.control.Table
    end

    
    properties (Access = private)
        Tabla_respaldo
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tabla_cargas.ColumnFormat = {'numeric', 'numeric', 'numeric'};
            app.tabla_medicion.ColumnFormat = {'numeric', 'numeric', 'numeric'};
            app.tabla_cargas.Data = cell(2,3);
            app.tabla_medicion.Data = cell(1,3);
        end

        % Cell edit callback: tabla_cargas
        function tabla_cargasCellEdit(app, event)
            
        end

        % Value changed function: numero_cargas
        function numero_cargasValueChanged(app, event)
            cantidad_cargas = app.numero_cargas.Value;
            [m,n] = size(app.tabla_cargas.Data);
            app.Tabla_respaldo = app.tabla_cargas.Data;
            if cantidad_cargas < m
                d = cell(cantidad_cargas,n);
                app.Tabla_respaldo = app.tabla_cargas.Data(1:cantidad_cargas, 1:n);
                d(1:cantidad_cargas,1:n) = app.Tabla_respaldo;
                app.tabla_cargas.Data = d;
            else 
                d = cell(cantidad_cargas,n);
                d(1:m,1:n) = app.Tabla_respaldo;
                app.tabla_cargas.Data = d;
            end
            
            
        end

        % Button pushed function: Calcular
        function CalcularButtonPushed(app, event)
        %Función de campo eléctrico por superposición
        [m,~] = size(app.tabla_cargas.Data);
        Eo = 8.854187817*10^(-12);
        k = 1/(4*pi*Eo);
        sumatoria = [0, 0];
        xp = cell2mat(app.tabla_medicion.Data(1, 1));
        display(xp)
        yp = cell2mat(app.tabla_medicion.Data(1, 2));
        display(yp)
        for i=1:m
            magQ = cell2mat(app.tabla_cargas.Data(i, 1));
            display(magQ);
            xq = cell2mat(app.tabla_cargas.Data(i, 2));
            display(xq);
            yq = cell2mat(app.tabla_cargas.Data(i, 3));
            display(yq);
            x = xp - xq;
            y = yp - yq;
            R = sqrt(x^2+y^2);
            U = [x/R, y/R];
            E = ((k*magQ)/(R^2))*U;
            sumatoria = sumatoria + E;
        end       
        display(sumatoria);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.interfaz = uifigure('Visible', 'off');
            app.interfaz.Name = 'MATLAB App';
            app.tabla_cargas = uitable(app.interfaz);
            app.tabla_cargas.ColumnName = {'Carga'; 'x'; 'y'};
            app.tabla_cargas.ColumnEditable = true;
            app.tabla_cargas.CellEditCallback = createCallbackFcn(app, @tabla_cargasCellEdit, true);
            app.NumerodecargasLabel = uilabel(app.interfaz);
            app.NumerodecargasLabel.Text = 'Numero de cargas';
            app.numero_cargas = uieditfield(app.interfaz, 'numeric');
            app.numero_cargas.Limits = [2 Inf];
            app.numero_cargas.RoundFractionalValues = 'on';
            app.numero_cargas.ValueChangedFcn = createCallbackFcn(app, @numero_cargasValueChanged, true);
            app.numero_cargas.Value = 2;
            app.PuntodemedicionLabel = uilabel(app.interfaz);
            app.PuntodemedicionLabel.Text = 'Punto de medicion';
            app.tabla_medicion = uitable(app.interfaz);
            app.tabla_medicion.ColumnName = {'x'; 'y'; 'z'};
            app.tabla_medicion.RowName = {};
            app.tabla_medicion.ColumnEditable = true;
            app.Etotal = uitable(app.interfaz);
            app.Etotal.ColumnName = {'x'; 'y'; 'z'};
            app.Etotal.RowName = {};
            app.Calcular = uibutton(app.interfaz, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.Calcular.Text = 'Calcular';
            % Show the figure after all components are created
            app.interfaz.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electric_field

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.interfaz)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.interfaz)
        end
    end
end